classdef HEVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationMenu                  matlab.ui.container.Menu
        ExportResultsMenu               matlab.ui.container.Menu
        LoadResultsMenu                 matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationonHEVP4Menu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        GridLayout38                    matlab.ui.container.GridLayout
        LoadSessionButton               matlab.ui.control.Button
        NewSessionButton                matlab.ui.control.Button
        WelcomeLabel                    matlab.ui.control.Label
        LoginImage                      matlab.ui.control.Image
        DriveCycleSetupTab              matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        DriveCycleOptionsPanel          matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        GridLayout56                    matlab.ui.container.GridLayout
        SourceVelocityUnitsDropDownLabel  matlab.ui.control.Label
        SourceVelocityUnitsDropDown     matlab.ui.control.DropDown
        WideOpenThrottleWOTPanel        matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        WOTTime                         matlab.ui.control.NumericEditField
        WOTTimeLabel                    matlab.ui.control.Label
        RefSpeed                        matlab.ui.control.NumericEditField
        RefSpeedLabel                   matlab.ui.control.Label
        FinSpeed                        matlab.ui.control.NumericEditField
        FinSpeedLabel                   matlab.ui.control.Label
        InSpeed                         matlab.ui.control.NumericEditField
        InSpeedLabel                    matlab.ui.control.Label
        DecTime                         matlab.ui.control.NumericEditField
        DecTimeLabel                    matlab.ui.control.Label
        StartTime                       matlab.ui.control.NumericEditField
        StartTimeLabel                  matlab.ui.control.Label
        GridLayout47                    matlab.ui.container.GridLayout
        RepeatcyclicallyCheckBox        matlab.ui.control.CheckBox
        EndSimulationTimeEditField      matlab.ui.control.NumericEditField
        EndSimulationTimesEditFieldLabel  matlab.ui.control.Label
        GridLayout30                    matlab.ui.container.GridLayout
        DriveCycleFromFileButton        matlab.ui.control.Button
        DriveCycleFromDropDown          matlab.ui.control.DropDown
        SelectdrivecycleLabel           matlab.ui.control.Label
        GridLayout40                    matlab.ui.container.GridLayout
        DriveCycleTable                 matlab.ui.control.Table
        DriveCycleAxes                  matlab.ui.control.UIAxes
        ModelParametersSetupTab         matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        EnginePanel                     matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        EngineTable                     matlab.ui.control.Table
        ECMLabel                        matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        SelectEngineTypeDropDown        matlab.ui.control.DropDown
        SelectEngineTypeDropDownLabel   matlab.ui.control.Label
        BatteryPanel                    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        BatteryTable                    matlab.ui.control.Table
        BatteryTypeLabel                matlab.ui.control.Label
        VehicleParametersPanel          matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout39_2                  matlab.ui.container.GridLayout
        InitialBatterySOCLabel          matlab.ui.control.Label
        InitialBatterySOCSlider         matlab.ui.control.Slider
        GridLayout24                    matlab.ui.container.GridLayout
        VehicleMassLabel                matlab.ui.control.Label
        UnloadedWheelRadiusLabel        matlab.ui.control.Label
        LoadedWheelRadiusLabel          matlab.ui.control.Label
        VehicleMassEditField            matlab.ui.control.NumericEditField
        UnloadedWheelRadiusEditField    matlab.ui.control.NumericEditField
        LoadedWheelRadiusEditField      matlab.ui.control.NumericEditField
        EnvironmentParametersPanel      matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        AtmosphericPressureLabel        matlab.ui.control.Label
        AtmosphericPressureSlider       matlab.ui.control.Slider
        GridLayout14                    matlab.ui.container.GridLayout
        TemperatureLabel                matlab.ui.control.Label
        LongitudinalWindSpeedLabel      matlab.ui.control.Label
        GradeLabel                      matlab.ui.control.Label
        LongitudinalWindSpeedEditField  matlab.ui.control.NumericEditField
        TemperatureSpinner              matlab.ui.control.Spinner
        GradeEditField                  matlab.ui.control.NumericEditField
        SimulationandVisualizationTab   matlab.ui.container.Tab
        GridLayout51                    matlab.ui.container.GridLayout
        VisualizationPanel              matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        GridLayout53                    matlab.ui.container.GridLayout
        ClearAllButton                  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        EmissionsPerformancePanel       matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        FuelEconomyL100kmCheckBox       matlab.ui.control.CheckBox
        USFuelEconomyCheckBox           matlab.ui.control.CheckBox
        CO2gkmCheckBox                  matlab.ui.control.CheckBox
        NOxgmiCheckBox                  matlab.ui.control.CheckBox
        COgmiCheckBox                   matlab.ui.control.CheckBox
        HCgmiCheckBox                   matlab.ui.control.CheckBox
        BatteryPanel_2                  matlab.ui.container.Panel
        GridLayout51_2                  matlab.ui.container.GridLayout
        BatteryCurrentCheckBox          matlab.ui.control.CheckBox
        BatterySOCCheckBox              matlab.ui.control.CheckBox
        EngineSpeedPanel                matlab.ui.container.Panel
        GridLayout50_2                  matlab.ui.container.GridLayout
        EngineMotorSpeedCheckBoxnn      matlab.ui.control.CheckBox
        EngineMotorTorqueCheckBox       matlab.ui.control.CheckBox
        VehicleSpeedCheckBox            matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        OutputPlotsLabel                matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ShowresultsLabel                matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        GenerateReportButton            matlab.ui.control.Button
        StartSimulationButton           matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        SimulationLamp                  matlab.ui.control.Lamp
        SessionLogTab                   matlab.ui.container.Tab
        GridLayout54                    matlab.ui.container.GridLayout
        SessionhistoryPanel             matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        LogTextArea                     matlab.ui.control.TextArea
        SessiondetailsPanel             matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        TimeLogLabel                    matlab.ui.control.Label
        ReportTab                       matlab.ui.container.Tab
        GridLayout55                    matlab.ui.container.GridLayout
        ReportAxes                      matlab.ui.control.UIAxes
    end


    properties
        SH % Simulation handler to manage simulation. TMB = Tunable Model Parameters
    end
    
    properties (Access = private)
        Data
        %
        PastResultsLabel matlab.ui.control.Label
        Listeners (:,1) event.listener
        %
        Session % To save/load a session
        TileContainer
        % Plots
        DriveCycleLine
        % Labels for results plots
        PlotLabels1 = {'Target','Engine', 'Engine'}
        PlotLabels2 = {'Actual','Motor' , 'Motor' }
        % Results
        ProgDlg
        PlotAxes
        PlotQuantity
        % Simulation
        Timer
        % Line object for current simulation
        ResultLine (2,:) = matlab.graphics.chart.primitive.Line
        % Line objects (past and difference) for comparing with previous
        % simulations
        PastLine (2,:) = matlab.graphics.chart.primitive.Line
        DiffLine (2,:) = matlab.graphics.chart.primitive.Line
        % Menus associated with each plot
        ContextMenu (:,1) matlab.ui.container.ContextMenu
        ShowMenu    (:,1) matlab.ui.container.Menu
        HideMenu    (:,1) matlab.ui.container.Menu
        ShowResMenu (:,1) matlab.ui.container.Menu
        ShowLatMenu (:,1) matlab.ui.container.Menu
        HideResMenu (:,1) matlab.ui.container.Menu
        ShowDifMenu (:,1) matlab.ui.container.Menu
        HideDifMenu (:,1) matlab.ui.container.Menu
        HideLatMenu (:,1) matlab.ui.container.Menu
        % Colors
        Green    = [0.1953 0.8008 0.1953]
        DarkRed  = [0.6953 0.1328 0.1328]
        DarkBlue = [0 0 0.5]
        SkyBlue  = [0 0.7461 0.9961]
        Orange   = [1 0.5 0]
    end

    methods (Access = private)

        function addSimulationListeners(app)
            app.Listeners(1) = addlistener(app.SH.TMB,"DriveCycleChanged", @app.onDriveCycleChanged);
            app.Listeners(end+1) = addlistener(app.SH,"Error", @app.onError);
            app.Listeners(end+1) = addlistener(app.SH.TMB,"Error", @app.onError);
            app.Listeners(end+1) = addlistener(app.SH,"PastResultsLoaded", @app.onPastResultsLoaded);
            app.Listeners(end+1) = addlistener(app.SH,"ResultsExported", @app.onResultsExported);
            app.Listeners(end+1) = addlistener(app.SH,"SimulationStarted", @app.onSimulationStarted);
            app.Listeners(end+1) = addlistener(app.SH,"SimulationEnded", @app.onSimulationEnded);
            app.Listeners(end+1) = addlistener(app.SH,"ShowLive","PostSet",@app.onResultModeChanged);
            app.Listeners(end+1) = addlistener(app.SH.TMB,"Units","PostSet",@app.onUnitsChanged);
            app.Listeners(end+1) = addlistener(app.SH.TMB,"Engine","PostSet",@app.onEngineChanged);
            app.Listeners(end+1) = addlistener(app.SH.TMB,"EndSimulationTime","PostSet",@app.onEndSimulationTimeChanged);
            app.Listeners(end+1) = addlistener(app.SH.TMB,"InitialSOC","PostSet",@app.onInitialSOCChanged);
        end

        % Add log information in the Log tab
        function addLogMessage(app, MsgType, varargin)

            % Construct headline
            DateTime = datestr(datetime);
            Date     = convertCharsToStrings(date);
            Time     = convertCharsToStrings(DateTime(13:end));
            Headline = strcat("------ On ", Date, " at ", Time, ":");
            
            % Select message to display
            switch MsgType
                case 'SimulationStarted'
                    Msg = ["Simulation started with the following configuration:";
                        "";
                        "End Simulation time (s): " + app.SH.TMB.EndSimulationTime;
                        "";
                        "Drive cycle parameters: ";
                        "Drive cycle source:    " + app.SH.TMB.DriveCycleType;
                        "Source Velocity Units: " + app.SH.TMB.Units];
                    if strcmp(app.SH.TMB.DriveCycleType,'Wide Open Throttle (WOT)')
                        Msg = [Msg;
                            app.StartTimeLabel.Text + "                 " + app.SH.TMB.StartTime;
                            app.InSpeedLabel.Text + "  " + app.SH.TMB.InSpeed;
                            app.RefSpeedLabel.Text + "  " + app.SH.TMB.RefSpeed;
                            app.DecTimeLabel.Text + " " + app.SH.TMB.DecTime;
                            app.FinSpeedLabel.Text + "    " + app.SH.TMB.FinSpeed;
                            app.WOTTimeLabel.Text + "       " + app.SH.TMB.WOTTime];
                    end
                    Msg = [Msg;
                        "";
                        %
                        "Environment parameters:";
                        "Wind Speed (m/s):           " + app.SH.TMB.WindSpeed;
                        "Grade (deg):                " + app.SH.TMB.Grade;
                        "Temperature (K):            " + app.SH.TMB.Temperature;
                        "Atmospheric Pressure (atm): " + app.SH.TMB.Pressure;
                        "";
                        %
                        "Vehicle parameters:";
                        "Mass (kg):                 " + app.SH.TMB.VehicleMass;
                        "Unloaded wheel radius (m): " + app.SH.TMB.UnloadedRadius;
                        "Loaded wheel radius (m):   " + app.SH.TMB.LoadedRadius;
                        "Initial Battery SOC (%):   " + app.SH.TMB.InitialSOC;
                        "Engine Type:               " + app.SH.TMB.Engine];
                case 'SimulationCompleted'
                    Msg = "Simulation terminated.";
                case 'NewSession'
                    Msg = "New session started.";
                case 'SavedSession'
                    Msg = "Session saved successfully.";
                case 'ReportGenerated'
                    Msg = "Simulation report created successfully.";
                case 'ReportFailed'
                    Msg = "Failed attempt to create simulation report.";
                case 'SavedAsSession'
                    Msg = "Session saved at: " + convertCharsToStrings(varargin{1});
                case 'LoadedSession'
                    Msg = "Session loaded from: " + convertCharsToStrings(varargin{1});
                case 'ResultsExported'
                    Msg = "Simulation results exported to: " + convertCharsToStrings(varargin{1});
                case 'LoadedResults'
                    Msg = "Past results loaded from: " + convertCharsToStrings(varargin{1});
            end
            if strcmp(app.LogTextArea.Value, "")
                % If log text area is empty
                app.LogTextArea.Value = [Headline; Msg; ""];
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; Headline; Msg; ""];
            end
        end

        function clearAllPlotChecks(app)
            % Uncheck all plots
            app.CO2gkmCheckBox.Value             = false;
            app.COgmiCheckBox.Value              = false;
            app.HCgmiCheckBox.Value              = false;
            app.NOxgmiCheckBox.Value             = false;
            app.USFuelEconomyCheckBox.Value      = false;
            app.BatterySOCCheckBox.Value         = false;
            app.BatteryCurrentCheckBox.Value     = false;
            app.EngineMotorTorqueCheckBox.Value  = false;
            app.EngineMotorSpeedCheckBoxnn.Value = false;
            app.VehicleSpeedCheckBox.Value       = false;
            app.FuelEconomyL100kmCheckBox.Value  = false;
        end

        % Create line objects for tiles in Simulation and Visualization tab
        function createTileLines(app,i,Res)

            % Distinguish between quantities with two lines (e.g., velocity) and one
            % line (like emissions)
            switch i
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    % Latest results
                    switch Res
                        case 'New'
                            app.ResultLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'r','linewidth',2);
                            app.ResultLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'b','linewidth',2);
                        case 'Old'
                            app.ResultLine(1,i) = plot(app.PlotAxes(i),app.SH.Results.Time{i},...
                                app.SH.Results.Data{i}(1,:),'r','linewidth',2);
                            app.ResultLine(2,i) = plot(app.PlotAxes(i),app.SH.Results.Time{i},...
                                app.SH.Results.Data{i}(2,:),'b','linewidth',2);
                    end
                    app.ResultLine(1,i).DisplayName = [app.PlotLabels1{i} ' (latest)'];
                    app.ResultLine(2,i).DisplayName = [app.PlotLabels2{i} ' (latest)'];
                    % Past results
                    app.PastLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.DarkRed,'linewidth',2,'HandleVisibility','off');
                    app.PastLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.DarkBlue,'linewidth',2,'HandleVisibility','off');
                    app.PastLine(1,i).DisplayName = [app.PlotLabels1{i} ' (past)'];
                    app.PastLine(2,i).DisplayName = [app.PlotLabels2{i} ' (past)'];
                    % Difference in results
                    app.DiffLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.Orange,'linestyle',':','linewidth',2,'HandleVisibility','off');
                    app.DiffLine(2,i) = plot(app.PlotAxes(i),NaN,NaN,'color',app.SkyBlue,'linestyle',':','linewidth',2,'HandleVisibility','off');
                    app.DiffLine(1,i).DisplayName = [app.PlotLabels1{i} ' (difference)'];
                    app.DiffLine(2,i).DisplayName = [app.PlotLabels2{i} ' (difference)'];
                otherwise
                    % Latest results
                    if strcmp(Res,'New')
                        app.ResultLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color','r','linewidth',2);
                        app.ResultLine(1,i).DisplayName = 'Latest';
                    elseif strcmp(Res,'Old')
                        app.ResultLine(1,i) = plot(app.PlotAxes(i),app.SH.Results.Time{i},...
                            app.SH.Results.Data{i},'r','linewidth',2);
                        app.ResultLine(1,i).DisplayName = 'Latest';
                    end
                    % Past results
                    app.PastLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color',app.DarkRed,'linewidth',2,'HandleVisibility','off');
                    app.PastLine(1,i).DisplayName = 'Past';
                    % Difference in results
                    app.DiffLine(1,i) = plot(app.PlotAxes(i),NaN,NaN,'Color',app.Orange,'linestyle',':','linewidth',2,'HandleVisibility','off');
                    app.DiffLine(1,i).DisplayName = 'Difference';
            end
            legend(app.PlotAxes(i),'box','off');
        end

        % Context menu item: hides the selected lines from the selected
        % plot
        function hideLineMenuSelected(app, source, ~)

            i = str2double(source.Parent.Parent.Tag);
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'off';
                    app.HideLatMenu(i).Checked = 'off';
                    l = app.ResultLine;
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'off';
                    app.HideResMenu(i).Checked = 'off';
                    l = app.PastLine;
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'off';
                    app.HideDifMenu(i).Checked = 'off';
                    l = app.DiffLine;
            end

            switch i
                % Velocity, engine speed and engine torque have
                % two lines
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    set(l(:,i),'XData',NaN,'YData',NaN,'HandleVisibility','off');
                otherwise
                    set(l(1,i),'XData',NaN,'YData',NaN,'HandleVisibility','off');
            end

        end

        % Callback function activated when Drive Cycle line is clicked on.
        % It reconfigures the line according to the movement of the mouse.
        function lineSelectedCallback(app, ~, ~)
            if strcmp(app.DriveCycleFromDropDown.Value, 'Wide Open Throttle (WOT)')
                % Margin of error within which vertical line can be selected
                Margin = 0.3;
                % Find which point on the axes was clicked
                SelectedTime = app.DriveCycleAxes.CurrentPoint;
                % Determine which part of the plot is to be modified. dir =
                % 1/2 (x- and y- directions). par = parameter to be changed
                if SelectedTime(1,1) <= app.SH.TMB.StartTime - Margin
                    dir = 2;
                    par = "InSpeed";
                    id = 0;
                elseif SelectedTime(1,1) <= app.SH.TMB.StartTime + 0.1 + Margin
                    dir = 1;
                    par = "StartTime";
                    id  = 1;
                elseif SelectedTime(1,1) <= app.SH.TMB.DecTime - Margin
                    dir = 2;
                    par = "RefSpeed";
                    id = 0;
                elseif SelectedTime(1,1) <= app.SH.TMB.DecTime + 0.1 + Margin
                    dir = 1;
                    par = "DecTime";
                    id = 2;
                else
                    dir = 2;
                    par = "FinSpeed";
                    id = 0;
                end
                % Change pointer according to selected segment
                PointerType = {'left','top'};
                app.UIFigure.Pointer = PointerType{dir};
                % Create callbacks
                app.UIFigure.WindowButtonMotionFcn = @moveDriveCycleLine;
                app.UIFigure.WindowButtonUpFcn = @restoreDef;
            end
            % Move line according to movement of the mouse
            function moveDriveCycleLine(~,~)
                val = max(0, round(app.DriveCycleAxes.CurrentPoint(1, dir), 1));
                % Edge cases: acceleration time must not exceed deceleration
                % time (case 2), and deceleration time must not exceed
                % final time (case 4)
                switch id
                    case 1
                        val = min(app.SH.TMB.DecTime - 0.1, val);
                    case 2
                        val = max(app.SH.TMB.StartTime + 0.1, min(app.SH.TMB.WOTTime - 0.1, val));
                end
                app.SH.TMB.(par) = val;
                app.(par).Value = val;
            end
            % Remove callbacks when user releases button
            function restoreDef(~,~)
                app.UIFigure.Pointer = 'arrow';
                app.UIFigure.WindowButtonMotionFcn = [];
                app.UIFigure.WindowButtonUpFcn     = [];
            end
        end

        function newSessionProcedures(app)
            app.DriveCycleSetupTab.Parent = app.TabGroup;

            % Show tabs
            app.TabGroup.SelectedTab = app.DriveCycleSetupTab;
            app.DriveCycleSetupTab.Parent            = app.TabGroup;
            app.ModelParametersSetupTab.Parent       = app.TabGroup;
            app.SimulationandVisualizationTab.Parent = app.TabGroup;
            app.SessionLogTab.Parent                 = app.TabGroup;

            % Enable save menu
            app.Session.SaveMenu.Enable = true;
            app.Session.SaveAsMenu.Enable = true;
            app.SimulationMenu.Visible = true;

            % Initialize arrays with size equal to number of outputs
            app.PlotQuantity = false(app.SH.NOutputs,1);
            app.PlotAxes = gobjects(app.SH.NOutputs,1);
        end

        function onDriveCycleChanged(app, ~, ~)
            % Update table
            app.DriveCycleTable.Data = [app.SH.TMB.DriveCycle.Time app.SH.TMB.DriveCycle.Data];
            % Update plot
            app.DriveCycleLine.XData = app.SH.TMB.DriveCycle.Time;
            app.DriveCycleLine.YData = app.SH.TMB.DriveCycle.Data;
            % Update plot title
            switch app.SH.TMB.DriveCycleType
                case 'FTP75'
                    app.DriveCycleAxes.Title.String = "FTP75 Drive Cycle";
                    app.WideOpenThrottleWOTPanel.Parent = [];
                    app.GridLayout56.Parent = [];
                    app.DriveCycleAxes.YLimMode = 'auto';
                case 'Wide Open Throttle (WOT)'
                    app.DriveCycleAxes.YLim = [-3 5+...
                        max([app.SH.TMB.InSpeed...
                        app.SH.TMB.RefSpeed...
                        app.SH.TMB.FinSpeed])];
                    app.DriveCycleAxes.Title.String = "Wide Open Throttle (WOT)";
                    app.WideOpenThrottleWOTPanel.Parent = app.GridLayout46;
                    app.GridLayout56.Parent = app.GridLayout46;
                case 'Custom'
                    app.DriveCycleFromDropDown.Value = app.DriveCycleFromDropDown.Items{1};
                    app.DriveCycleAxes.YLimMode = 'auto';
                    app.DriveCycleAxes.Title.String = "Custom Drive Cycle";
                    app.WideOpenThrottleWOTPanel.Parent = [];
                    app.GridLayout56.Parent = app.GridLayout46;
            end
            % Update simulation time
            app.EndSimulationTimeEditField.Value = app.SH.TMB.EndSimulationTime;
            app.RepeatcyclicallyCheckBox.Value = app.SH.TMB.EndSimulationTime > app.SH.TMB.DriveCycle.Time(end);
        end

        function onEngineChanged(app, ~, ~)
            switch app.SH.TMB.Engine
                case 'SiMappedEngine'
                    app.EngineTable.Data = app.SH.TMB.SiMappedEngineTabData;
                case 'SiEngine'
                    app.EngineTable.Data = app.SH.TMB.SiEngineTabData;
            end
        end
        
        function onError(app, ~, e)
            uialert(app.UIFigure, e.Data{1}, e.Data{2}, Icon="error");
        end
        
        function onResultsExported(app, ~, e)
            addLogMessage(app, 'ResultsExported', e.Data);
        end

        function onInitialSOCChanged(app, ~, ~)
            app.BatteryTable.Data(contains(app.BatteryTable.Data(:,1),'BattCapInit'), 2) = ...
                {num2str(app.InitialBatterySOCSlider.Value*app.SH.TMB.BatteryParam.('BattChargeMax')/100)};
        end

        function onLoadSession(app, ~, e)
            if ~app.Session.isSession
                newSessionProcedures(app)
            end
            clearAllPlotChecks(app)
            delete(app.PlotAxes)
            app.GenerateReportButton.Enable = false;
            app.ExportResultsMenu.Enable    = false;
            % Add listeners to new object
            addSimulationListeners(app)
            setDriveCycle(app.SH.TMB, app.DriveCycleTable.Data(:,1), app.DriveCycleTable.Data(:,2))
            
            % Restore graphics components
            for i = 1:app.SH.NOutputs
                app.ShowLatMenu(i).Checked = false;
                app.HideLatMenu(i).Checked = false;
                app.ShowResMenu(i).Checked = false;
                app.ShowDifMenu(i).Checked = false;
                app.HideResMenu(i).Checked = false;
                app.HideDifMenu(i).Checked = false;
            end
            % Log data
            addLogMessage(app,'LoadedSession', e.Data);
        end

        function onNewSession(app, ~, ~)
            if ~app.Session.isSession
                newSessionProcedures(app)
            end
            restore(app.SH)
            %onDriveCycleChanged(app,0,0)
            % Restore graphics components
            for i = 1:app.SH.NOutputs
                app.ShowLatMenu(i).Checked = false;
                app.HideLatMenu(i).Checked = false;
                app.ShowResMenu(i).Checked = false;
                app.ShowDifMenu(i).Checked = false;
                app.HideResMenu(i).Checked = false;
                app.HideDifMenu(i).Checked = false;
            end
            
            delete(app.PlotAxes)
            % Add log information
            addLogMessage(app,'NewSession');
        end

        function onSavedAsSession(app, ~, e)
            addLogMessage(app,'SavedAsSession', e.Data);
        end

        function onSavedSession(app, ~, ~)
            addLogMessage(app,'SavedSession');
        end

        function onPastResultsLoaded(app, ~, e)
            % Enable context menu items
            for i = 1:app.SH.NOutputs
                app.ShowResMenu(i).Enable = true;
                app.HideResMenu(i).Enable = true;
            end
            % Enable diff menu only if a simulation was run before
            if app.SH.WasSimulating
                for i = 1:app.SH.NOutputs
                    app.ShowDifMenu(i).Enable = true;
                    app.HideDifMenu(i).Enable = true;
                end
            end
            % Create PastResultsLabel
            if isempty(app.PastResultsLabel)
                app.PastResultsLabel = uilabel(app.GridLayout51);
                app.PastResultsLabel.HorizontalAlignment = 'center';
                app.PastResultsLabel.Layout.Row = 3;
                app.PastResultsLabel.Layout.Column = 1;
                app.PastResultsLabel.Text = '';
            end
            app.PastResultsLabel.Text = ['Past results loaded from: ' e.Data];
            addLogMessage(app,'LoadedResults', e.Data);
        end

        function onResultModeChanged(app, ~, ~)
            app.SimulationLamp.Visible = app.SH.ShowLive;
            app.StatusLabel.Visible = app.SH.ShowLive;
        end

        function onSimulationEnded(app, ~, ~)
            % Stop timer if results are displayed live
            if app.SH.ShowLive
                stop(app.Timer)
                app.Switch.Enable = true;
            else
                updatePlots(app)
                close(app.ProgDlg)
            end
            app.SH.ProgressPercentage = 0;
            app.StartSimulationButton.Text = "Start Simulation";
            app.StartSimulationButton.Icon = 'greenarrowicon.gif';
            app.Switch.Enable = true;
            setSimulationLamp(app, app.Green, 'Ready to simulate')
            % If was first simulation, enable report generation and export
            % results
            if app.SH.IsPastResultsLoaded
                for i = 1:app.SH.NOutputs
                    app.ShowDifMenu(i).Enable = true;
                    app.HideDifMenu(i).Enable = true;
                end
            end
            if ~app.GenerateReportButton.Enable
                for i = 1:app.SH.NOutputs
                    app.ShowLatMenu(i).Enable = true;
                    app.HideLatMenu(i).Enable = true;
                end
                app.GenerateReportButton.Enable = true;
                app.ExportResultsMenu.Enable = true;
            end
            % Add log information about the simulation
            addLogMessage(app,'SimulationCompleted');
        end

        function onSimulationStarted(app, ~, ~)
            if app.SH.ShowLive
                start(app.Timer)
                app.Switch.Enable = false;
            else
                app.ProgDlg = uiprogressdlg(app.UIFigure,"Message","Running simulation...", Indeterminate="on", Title="Simulation Status", Icon="info");
            end
            for i = 1:app.SH.NOutputs
                app.ShowLatMenu(i).Checked = true;
                app.HideLatMenu(i).Checked = true;
            end
            app.StartSimulationButton.Icon = 'stop_icon.png';
            app.StartSimulationButton.Text = "Stop Simulation";
            setSimulationLamp(app, app.DarkBlue, 'Simulating (0%)')
            addLogMessage(app,'SimulationStarted');
            drawnow
        end
        
        function onEndSimulationTimeChanged(app, ~, ~)
            app.RepeatcyclicallyCheckBox.Value = app.SH.TMB.EndSimulationTime > app.SH.TMB.DriveCycle.Time(end);
        end

        function onUnitsChanged(app, ~, ~)
            app.DriveCycleTable.ColumnName{2} = ['Velocity (' app.SH.TMB.Units ')'];
            app.DriveCycleAxes.YLabel.String  = ['Velocity (' app.SH.TMB.Units ')'];
            app.SourceVelocityUnitsDropDown.Value = app.SH.TMB.Units;
        end

        % Control simulation lamp color and text
        function setSimulationLamp(app, Color, Text)
            app.SimulationLamp.Color  = Color;
            app.StatusLabel.FontColor = Color;
            app.StatusLabel.Text      = Text;
        end
        
                % Context menu item: show results (latest, past, difference)
        function showLineMenuSelected(app, source, ~)
            
            i = str2double(source.Parent.Parent.Tag);
            % set l = line object, t = time array, d = data array
            switch source.Text
                case 'Latest Results'
                    app.ShowLatMenu(i).Checked = 'on';
                    app.HideLatMenu(i).Checked = 'on';
                    l = app.ResultLine;
                    t = app.SH.Results.Time{i};
                    d = app.SH.Results.Data{i};
                case 'Past Results'
                    app.ShowResMenu(i).Checked = 'on';
                    app.HideResMenu(i).Checked = 'on';
                    l = app.PastLine;
                    t = app.SH.PastResults.Time{i};
                    d = app.SH.PastResults.Data{i};
                case 'Difference'
                    app.ShowDifMenu(i).Checked = 'on';
                    app.HideDifMenu(i).Checked = 'on';
                    % Find which simulation has the lowest time
                    [~, idx] = min([app.SH.PastResults.Time{i}(end) app.SH.Results.Time{i}(end)]);
                    
                    % Construct useful variables for later manipulation
                    Time = {app.SH.PastResults.Time{i} app.SH.Results.Time{i}};
                    Res  = {app.SH.PastResults.Data app.SH.Results.Data};
                    
                    % Find unique times for interpolation
                    [Time{idx}, UniqueIdx]    = unique(Time{idx});
                    [Time{3-idx}, UniqueIdx2] = unique(Time{3-idx});
                    
                    % Construct array of results to interpolate to shorter
                    % time array
                    FittedResult   = Res{idx};
                    ResultToInterp = Res{3 - idx};
                    ResultToInterp = ResultToInterp{i}(:,UniqueIdx2);
                    
                    l = app.DiffLine;
                    t = Time{idx};
                    if i <= 3
                        d = -FittedResult{i}(1,UniqueIdx) + ...
                            interp1(Time{3 - idx},ResultToInterp(1,:),Time{idx});
                        d = [d; -FittedResult{i}(2,UniqueIdx) + ...
                            interp1(Time{3 - idx},ResultToInterp(2,:),Time{idx})];
                    else
                        d = -FittedResult{i}(:,UniqueIdx) + ...
                            interp1(Time{3 - idx},ResultToInterp,Time{idx});
                    end
            end

            switch i
                case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                        app.SH.Map("EngSpd, MotSpd (RPM)")
                        app.SH.Map("EngTrq, MotTrq (Nm)")}
                    set(l(1,i),'XData',t,'YData',d(1,:),'HandleVisibility','on');
                    set(l(2,i),'XData',t,'YData',d(2,:),'HandleVisibility','on');
                otherwise
                    set(l(1,i),'XData',t,'YData',d,'HandleVisibility','on');
            end
        end

        function updatePlotTiles(app, OutputName, ToPlot)
            idx = app.SH.Map(OutputName);
            app.PlotQuantity(idx) = ToPlot;
            if ToPlot
                % Add tile
                app.PlotAxes(idx) = nexttile(app.TileContainer);
                hold(app.PlotAxes(idx),'on')
                grid(app.PlotAxes(idx),'on')
                app.PlotAxes(idx).Title.String = OutputName;
                app.PlotAxes(idx).ContextMenu  = app.ContextMenu(idx);
                % Parentize the relevant lines
                if app.SH.WasSimulating
                    createTileLines(app, idx, 'Old')
                else
                    createTileLines(app, idx, 'New');
                end
            else
                % Remove tile
                delete(app.PlotAxes(idx))
            end
        end

        function updateSimulationGraphics(app, ~, ~)
            app.StatusLabel.Text = ['Simulating (' num2str(app.SH.ProgressPercentage)  '%)'];
            updatePlots(app)
        end

        function updatePlots(app)
            for opIdx = 1:app.SH.NOutputs
                if app.PlotQuantity(opIdx)
                    if ~isempty(app.SH.Results.Time{opIdx})
                        switch opIdx
                            case {app.SH.Map("Trace Velocity, Target, Actual (mph)")
                                    app.SH.Map("EngSpd, MotSpd (RPM)")
                                    app.SH.Map("EngTrq, MotTrq (Nm)")}
                                set(app.ResultLine(1,opIdx),"XData",app.SH.Results.Time{opIdx},...
                                    "YData",app.SH.Results.Data{opIdx}(1,:));
                                set(app.ResultLine(2,opIdx),"XData",app.SH.Results.Time{opIdx},...
                                    "YData",app.SH.Results.Data{opIdx}(2,:));
                            otherwise
                                set(app.ResultLine(1,opIdx),"XData",app.SH.Results.Time{opIdx},...
                                    "YData",app.SH.Results.Data{opIdx});
                        end
                    end
                end
            end
            drawnow limitrate
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place figure at the centre of the screen
            g = groot;
            app.UIFigure.Position = [(g.ScreenSize(3:4)-app.UIFigure.Position(3:4))/2 app.UIFigure.Position(3:4)];
            
            % Create simulation controller
            app.SH = SimulationHandler;
            addSimulationListeners(app)
            
            % Initialize drive cycle line
            app.DriveCycleLine = line(app.DriveCycleAxes, NaN, NaN, Linewidth=2, Color="k", ButtonDownFcn=@app.lineSelectedCallback);

            % Ready to simulate!
            setSimulationLamp(app, app.Green, 'Ready to simulate')

            % Initialize WOT field values
            app.StartTime.Value = app.SH.TMB.StartTime;
            app.InSpeed.Value = app.SH.TMB.InSpeed;
            app.RefSpeed.Value = app.SH.TMB.RefSpeed;
            app.FinSpeed.Value = app.SH.TMB.FinSpeed;
            app.DecTime.Value = app.SH.TMB.DecTime;
            app.WOTTime.Value = app.SH.TMB.WOTTime;

            % Initialize environment and vehicle parameters
            app.TemperatureSpinner.Value = app.SH.TMB.Temperature;
            app.AtmosphericPressureSlider.Value = app.SH.TMB.Pressure;
            app.GradeEditField.Value = app.SH.TMB.Grade;
            app.LongitudinalWindSpeedEditField.Value = app.SH.TMB.WindSpeed;
            app.LoadedWheelRadiusEditField.Value = app.SH.TMB.LoadedRadius;
            app.UnloadedWheelRadiusEditField.Value = app.SH.TMB.UnloadedRadius;
            app.VehicleMassEditField.Value = app.SH.TMB.VehicleMass;
            app.InitialBatterySOCSlider.Value = app.SH.TMB.InitialSOC;
            app.EndSimulationTimeEditField.Value = app.SH.TMB.EndSimulationTime;

            app.EngineTable.Data = app.SH.TMB.SiMappedEngineTabData;
            app.BatteryTable.Data = app.SH.TMB.BatteryTable;

            % Create session menu (after initializing graphics objects), disable save, add listeners for
            % load/save, reorder menu items
            app.Session = AppSession(app);
            app.Listeners(end+1) = addlistener(app.Session, "NewSession", @app.onNewSession);
            app.Listeners(end+1) = addlistener(app.Session, "LoadSession", @app.onLoadSession);
            app.Listeners(end+1) = addlistener(app.Session, "SavedSession", @app.onSavedSession);
            app.Listeners(end+1) = addlistener(app.Session, "SavedAsSession", @app.onSavedAsSession);
            app.Session.SaveMenu.Enable = false;
            app.Session.SaveAsMenu.Enable = false;
            app.UIFigure.Children = app.UIFigure.Children([3 4 1 2]);

            % Hide tabs
            app.DriveCycleSetupTab.Parent            = [];
            app.ModelParametersSetupTab.Parent       = [];
            app.SimulationandVisualizationTab.Parent = [];
            app.SessionLogTab.Parent                 = [];
            app.ReportTab.Parent                     = [];
            app.WideOpenThrottleWOTPanel.Parent      = [];
            app.GridLayout56.Parent                  = [];

            % Create ContextMenu for plots (one for each plot) in
            % Simulation and Visualization tab
            for i = 1:app.SH.NOutputs
                app.ContextMenu(i) = uicontextmenu(app.UIFigure, Tag=num2str(i));
                app.ShowMenu(i)    = uimenu(app.ContextMenu(i), Text="Show");
                app.ShowResMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Past Results", Enable="off");
                app.ShowLatMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Latest Results", Enable="off");
                app.ShowDifMenu(i) = uimenu(app.ShowMenu(i), MenuSelectedFcn=@app.showLineMenuSelected, Text="Difference", Enable="off");
                app.HideMenu(i)    = uimenu(app.ContextMenu(i), Text="Hide");
                app.HideResMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Past Results", Enable="off");
                app.HideLatMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Latest Results", Enable="off");
                app.HideDifMenu(i) = uimenu(app.HideMenu(i), MenuSelectedFcn=@app.hideLineMenuSelected, Text="Difference", Enable="off");
            end

            % Initialize Tile Container
            app.TileContainer = tiledlayout("flow", Parent=app.VisualizationPanel, Padding="compact", TileSpacing="compact");
            
            % Show time of login
            app.TimeLogLabel.Text = ['Session started on: ' datestr(datetime)];

            % Define timer that calls drawnow every 0.5 seconds
            app.Timer = timer(ExecutionMode="fixedRate", BusyMode="drop", Period=0.5, TimerFcn=@app.updateSimulationGraphics);
        end

        % Menu selected function: DocumentationonHEVP4Menu
        function DocumentationonHEVP4MenuSelected(app, event)
            % Open Documentation page on HEV P4 model
            web('https://www.mathworks.com/help/autoblks/ug/explore-the-hybrid-electric-vehicle-p4-reference-application.html')
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            newMenuSelected(app.Session,0,0)
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            loadMenuSelected(app.Session,0,0)
        end

        % Value changed function: EndSimulationTimeEditField
        function EndSimulationTimeEditFieldValueChanged(app, event)
            app.SH.TMB.EndSimulationTime = app.EndSimulationTimeEditField.Value;
        end

        % Cell edit callback: DriveCycleTable
        function DriveCycleTableCellEdit(app, event)
            setDriveCycle(app.SH.TMB, app.DriveCycleTable.Data(:,1), app.DriveCycleTable.Data(:,2))
        end

        % Button pushed function: DriveCycleFromFileButton
        function DriveCycleFromFileButtonPushed(app, event)
            loadDriveCycle(app.SH.TMB, "Custom")
        end

        % Value changed function: DriveCycleFromDropDown
        function DriveCycleFromDropDownValueChanged(app, event)
            loadDriveCycle(app.SH.TMB, app.DriveCycleFromDropDown.Value)
        end

        % Value changed function: StartTime
        function StartTimeValueChanged(app, event)
            app.SH.TMB.StartTime = app.StartTime.Value;
        end

        % Value changed function: InSpeed
        function InSpeedValueChanged(app, event)
            app.SH.TMB.InSpeed = app.InSpeed.Value;
        end

        % Value changed function: RefSpeed
        function RefSpeedValueChanged(app, event)
            app.SH.TMB.RefSpeed = app.RefSpeed.Value;
        end

        % Value changed function: DecTime
        function DecTimeValueChanged(app, event)
            app.SH.TMB.DecTime = app.DecTime.Value;
        end

        % Value changed function: FinSpeed
        function FinSpeedValueChanged(app, event)
            app.SH.TMB.FinSpeed = app.FinSpeed.Value;
        end

        % Value changed function: WOTTime
        function WOTTimeValueChanged(app, event)
            app.SH.TMB.WOTTime = app.WOTTime.Value;
        end

        % Value changed function: SourceVelocityUnitsDropDown
        function SourceVelocityUnitsDropDownValueChanged(app, event)
            app.SH.TMB.Units = app.SourceVelocityUnitsDropDown.Value;
        end

        % Value changed function: InitialBatterySOCSlider
        function InitialBatterySOCSliderValueChanged(app, event)
            app.SH.TMB.InitialSOC = app.InitialBatterySOCSlider.Value;
        end

        % Value changed function: SelectEngineTypeDropDown
        function SelectEngineTypeDropDownValueChanged(app, event)
            app.SH.TMB.Engine = app.SelectEngineTypeDropDown.Value;
        end

        % Value changed function: VehicleSpeedCheckBox
        function VehicleSpeedCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Trace Velocity, Target, Actual (mph)", app.VehicleSpeedCheckBox.Value)
        end

        % Value changed function: EngineMotorTorqueCheckBox
        function EngineMotorTorqueCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "EngTrq, MotTrq (Nm)", app.EngineMotorTorqueCheckBox.Value)
        end

        % Value changed function: EngineMotorSpeedCheckBoxnn
        function EngineMotorSpeedCheckBoxnnValueChanged(app, event)
            updatePlotTiles(app, "EngSpd, MotSpd (RPM)", app.EngineMotorSpeedCheckBoxnn.Value)
        end

        % Value changed function: BatterySOCCheckBox
        function BatterySOCCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Battery SOC", app.BatterySOCCheckBox.Value)
        end

        % Value changed function: BatteryCurrentCheckBox
        function BatteryCurrentCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Battery Current (A)", app.BatteryCurrentCheckBox.Value)
        end

        % Value changed function: HCgmiCheckBox
        function HCgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "HC (g/mi)", app.HCgmiCheckBox.Value)
        end

        % Value changed function: COgmiCheckBox
        function COgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "CO (g/mi)", app.COgmiCheckBox.Value)
        end

        % Value changed function: NOxgmiCheckBox
        function NOxgmiCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "NOx (g/mi)", app.NOxgmiCheckBox.Value)
        end

        % Value changed function: CO2gkmCheckBox
        function CO2gkmCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "CO2 (g/km)", app.CO2gkmCheckBox.Value)
        end

        % Value changed function: USFuelEconomyCheckBox
        function USFuelEconomyCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Fuel Economy (US MPGe)", app.USFuelEconomyCheckBox.Value)
        end

        % Value changed function: FuelEconomyL100kmCheckBox
        function FuelEconomyL100kmCheckBoxValueChanged(app, event)
            updatePlotTiles(app, "Fuel Economy (L/100km)", app.FuelEconomyL100kmCheckBox.Value)
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            clearAllPlotChecks(app)
            app.PlotQuantity(:) = false;

            % Update number of active simulation plots
            for i = 1:app.SH.NOutputs
                if ~app.SH.WasSimulating
                    app.ShowLatMenu(i).Checked = 'off';
                    app.ShowResMenu(i).Checked = 'off';
                end
                app.ShowDifMenu(i).Checked = 'off';
                app.ShowResMenu(i).Checked = 'off';
                app.HideResMenu(i).Checked = 'off';
                app.HideDifMenu(i).Checked = 'off';
            end
            
            % Delete plots
            delete(app.PlotAxes)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            startOrStopSimulation(app.SH)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.SH.ShowLive = strcmp(app.Switch.Value, "Live");
        end

        % Value changed function: LongitudinalWindSpeedEditField
        function LongitudinalWindSpeedEditFieldValueChanged(app, event)
            app.SH.TMB.WindSpeed = app.LongitudinalWindSpeedEditField.Value;
        end

        % Value changed function: GradeEditField
        function GradeEditFieldValueChanged(app, event)
            app.SH.TMB.Grade = app.GradeEditField.Value;
        end

        % Value changed function: TemperatureSpinner
        function TemperatureSpinnerValueChanged(app, event)
            app.SH.TMB.Temperature = app.TemperatureSpinner.Value;
        end

        % Value changed function: AtmosphericPressureSlider
        function AtmosphericPressureSliderValueChanged(app, event)
            app.SH.TMB.Pressure = app.AtmosphericPressureSlider.Value;
        end

        % Value changed function: LoadedWheelRadiusEditField
        function LoadedWheelRadiusEditFieldValueChanged(app, event)
            app.SH.TMB.LoadedRadius = app.LoadedWheelRadiusEditField.Value;
        end

        % Value changed function: UnloadedWheelRadiusEditField
        function UnloadedWheelRadiusEditFieldValueChanged(app, event)
            app.SH.TMB.UnloadedRadius = app.UnloadedWheelRadiusEditField.Value;
        end

        % Value changed function: VehicleMassEditField
        function VehicleMassEditFieldValueChanged(app, event)
            app.SH.TMB.VehicleMass = app.VehicleMassEditField.Value;
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            % The axis called by the report generator (in Report Tab) needs to have a
            % parent
            app.ReportTab.Parent = app.TabGroup;

            ProgressBar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Creating simulation report...','ShowPercentage',true);
            
            % Create simulation report
            fid = HEVReportGen(app.SH, app.Session.Username, app.ReportAxes, app.DriveCycleAxes, ProgressBar);

            ProgressBar.Value = 1;
            close(ProgressBar)

            if ~fid
                % Report generation failed
                uialert(app.UIFigure, lasterr, 'Error') %#ok<*LERR>
                addLogMessage(app, "ReportFailed")
            else
                % Report generation successful
                uialert(app.UIFigure,"Report generated successfully.","Report Outcome","Icon","success")
                addLogMessage(app, "ReportGenerated")
            end
            app.ReportTab.Parent = [];
        end

        % Menu selected function: LoadResultsMenu
        function LoadResultsMenuSelected(app, event)
            loadResults(app.SH)
        end

        % Menu selected function: ExportResultsMenu
        function ExportResultsMenuSelected(app, event)
            exportResults(app.SH)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HEV P4 Reference Application';
            app.UIFigure.Icon = 'plotpicker-idnlhwplot.png';
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Visible = 'off';
            app.SimulationMenu.Text = 'Simulation';
            app.ExportResultsMenu = uimenu(app.SimulationMenu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultsMenuSelected, true);
            app.ExportResultsMenu.Enable = 'off';
            app.ExportResultsMenu.Text = 'Export Results...';
            app.LoadResultsMenu = uimenu(app.SimulationMenu);
            app.LoadResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultsMenuSelected, true);
            app.LoadResultsMenu.Text = 'Load Results...';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationonHEVP4Menu = uimenu(app.HelpMenu);
            app.DocumentationonHEVP4Menu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationonHEVP4MenuSelected, true);
            app.DocumentationonHEVP4Menu.Accelerator = 'H';
            app.DocumentationonHEVP4Menu.Text = 'Documentation on HEV P4';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GridLayout38 = uigridlayout(app.StartTab);
            app.LoginImage = uiimage(app.GridLayout38);
            app.LoginImage.Tooltip = {'Schematics of the HEV P4 Reference Model in Simulink'};
            app.LoginImage.ImageSource = 'model.JPG';
            app.WelcomeLabel = uilabel(app.GridLayout38);
            app.WelcomeLabel.Text = 'Welcome! Start a new session or load a pre-saved session';
            app.NewSessionButton = uibutton(app.GridLayout38, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Tooltip = {'Click to start a new session'};
            app.NewSessionButton.Text = 'New Session';
            app.LoadSessionButton = uibutton(app.GridLayout38, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Tooltip = {'Click to load a saved session'};
            app.LoadSessionButton.Text = 'Load Session';
            app.DriveCycleSetupTab = uitab(app.TabGroup);
            app.DriveCycleSetupTab.Title = 'Drive Cycle Setup';
            app.GridLayout39 = uigridlayout(app.DriveCycleSetupTab);
            app.GridLayout40 = uigridlayout(app.GridLayout39);
            app.DriveCycleAxes = uiaxes(app.GridLayout40);
            title(app.DriveCycleAxes, 'FTP75 Drive Cycle')
            xlabel(app.DriveCycleAxes, 'Time (s)')
            ylabel(app.DriveCycleAxes, 'Velocity (m/s)')
            app.DriveCycleTable = uitable(app.GridLayout40);
            app.DriveCycleTable.ColumnName = {'Time (s)'; 'Velocity (m/s)'};
            app.DriveCycleTable.RowName = {};
            app.DriveCycleTable.ColumnEditable = [false true];
            app.DriveCycleTable.CellEditCallback = createCallbackFcn(app, @DriveCycleTableCellEdit, true);
            app.GridLayout41 = uigridlayout(app.GridLayout39);
            app.GridLayout30 = uigridlayout(app.GridLayout41);
            app.SelectdrivecycleLabel = uilabel(app.GridLayout30);
            app.SelectdrivecycleLabel.Text = 'Select drive cycle:';
            app.DriveCycleFromDropDown = uidropdown(app.GridLayout30);
            app.DriveCycleFromDropDown.Items = {'From MATLAB...', 'FTP75', 'Wide Open Throttle (WOT)'};
            app.DriveCycleFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DriveCycleFromDropDownValueChanged, true);
            app.DriveCycleFromDropDown.Tooltip = {'Choose from pre-defined drive cycles'};
            app.DriveCycleFromDropDown.Value = 'FTP75';
            app.DriveCycleFromFileButton = uibutton(app.GridLayout30, 'push');
            app.DriveCycleFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @DriveCycleFromFileButtonPushed, true);
            app.DriveCycleFromFileButton.Icon = 'upfolder.gif';
            app.DriveCycleFromFileButton.Tooltip = {'Import drive cycle from file (.mat, .xlsx, .xls, .txt)'};
            app.DriveCycleFromFileButton.Text = 'From File...';
            app.DriveCycleOptionsPanel = uipanel(app.GridLayout41);
            app.DriveCycleOptionsPanel.Title = '   Drive Cycle Options';
            app.GridLayout46 = uigridlayout(app.DriveCycleOptionsPanel);
            app.GridLayout47 = uigridlayout(app.GridLayout46);
            app.EndSimulationTimesEditFieldLabel = uilabel(app.GridLayout47);
            app.EndSimulationTimesEditFieldLabel.Text = 'End Simulation Time (s):';
            app.EndSimulationTimeEditField = uieditfield(app.GridLayout47, 'numeric');
            app.EndSimulationTimeEditField.Limits = [0 Inf];
            app.EndSimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndSimulationTimeEditFieldValueChanged, true);
            app.RepeatcyclicallyCheckBox = uicheckbox(app.GridLayout47);
            app.RepeatcyclicallyCheckBox.Enable = 'off';
            app.RepeatcyclicallyCheckBox.Text = 'Repeat cyclically';
            app.WideOpenThrottleWOTPanel = uipanel(app.GridLayout46);
            app.WideOpenThrottleWOTPanel.Title = '   Wide Open Throttle (WOT)';
            app.GridLayout49 = uigridlayout(app.WideOpenThrottleWOTPanel);
            app.StartTimeLabel = uilabel(app.GridLayout49);
            app.StartTimeLabel.Text = 'Start time, t_wot1 [s]:';
            app.StartTime = uieditfield(app.GridLayout49, 'numeric');
            app.StartTime.Limits = [0 Inf];
            app.StartTime.ValueChangedFcn = createCallbackFcn(app, @StartTimeValueChanged, true);
            app.DecTimeLabel = uilabel(app.GridLayout49);
            app.DecTimeLabel.Text = 'Time to start deceleration, t_wot2 [s]:';
            app.DecTime = uieditfield(app.GridLayout49, 'numeric');
            app.DecTime.Limits = [0 Inf];
            app.DecTime.ValueChangedFcn = createCallbackFcn(app, @DecTimeValueChanged, true);
            app.InSpeedLabel = uilabel(app.GridLayout49);
            app.InSpeedLabel.Text = 'Initial reference speed, xdot_woto []:';
            app.InSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.InSpeed.Limits = [0 Inf];
            app.InSpeed.ValueChangedFcn = createCallbackFcn(app, @InSpeedValueChanged, true);
            app.FinSpeedLabel = uilabel(app.GridLayout49);
            app.FinSpeedLabel.Text = 'Final reference speed, xdot_wot2 []:';
            app.FinSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.FinSpeed.Limits = [0 Inf];
            app.FinSpeed.ValueChangedFcn = createCallbackFcn(app, @FinSpeedValueChanged, true);
            app.RefSpeedLabel = uilabel(app.GridLayout49);
            app.RefSpeedLabel.Text = 'Nominal reference speed, xdot_wot1 []:';
            app.RefSpeed = uieditfield(app.GridLayout49, 'numeric');
            app.RefSpeed.Limits = [0 Inf];
            app.RefSpeed.ValueChangedFcn = createCallbackFcn(app, @RefSpeedValueChanged, true);
            app.WOTTimeLabel = uilabel(app.GridLayout49);
            app.WOTTimeLabel.Text = 'WOT simulation time, t_wotend []:';
            app.WOTTime = uieditfield(app.GridLayout49, 'numeric');
            app.WOTTime.Limits = [0 Inf];
            app.WOTTime.ValueChangedFcn = createCallbackFcn(app, @WOTTimeValueChanged, true);
            app.GridLayout56 = uigridlayout(app.GridLayout46);
            app.SourceVelocityUnitsDropDown = uidropdown(app.GridLayout56);
            app.SourceVelocityUnitsDropDown.Items = {'m/s', 'km/h', 'mph'};
            app.SourceVelocityUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceVelocityUnitsDropDownValueChanged, true);
            app.SourceVelocityUnitsDropDown.Value = 'm/s';
            app.SourceVelocityUnitsDropDownLabel = uilabel(app.GridLayout56);
            app.SourceVelocityUnitsDropDownLabel.Text = 'Source Velocity Units:';
            app.ModelParametersSetupTab = uitab(app.TabGroup);
            app.ModelParametersSetupTab.Title = 'Model Parameters Setup';
            app.GridLayout50 = uigridlayout(app.ModelParametersSetupTab);
            app.EnvironmentParametersPanel = uipanel(app.GridLayout50);
            app.EnvironmentParametersPanel.Title = '   Environment Parameters';
            app.GridLayout13 = uigridlayout(app.EnvironmentParametersPanel);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.GradeEditField = uieditfield(app.GridLayout14, 'numeric');
            app.GradeEditField.Limits = [0 Inf];
            app.GradeEditField.ValueChangedFcn = createCallbackFcn(app, @GradeEditFieldValueChanged, true);
            app.TemperatureSpinner = uispinner(app.GridLayout14);
            app.TemperatureSpinner.Limits = [0 Inf];
            app.TemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @TemperatureSpinnerValueChanged, true);
            app.LongitudinalWindSpeedEditField = uieditfield(app.GridLayout14, 'numeric');
            app.LongitudinalWindSpeedEditField.Limits = [0 Inf];
            app.LongitudinalWindSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudinalWindSpeedEditFieldValueChanged, true);
            app.GradeLabel = uilabel(app.GridLayout14);
            app.GradeLabel.Text = 'Grade (deg):';
            app.LongitudinalWindSpeedLabel = uilabel(app.GridLayout14);
            app.LongitudinalWindSpeedLabel.Text = 'Longitudinal Wind Speed (m/s):';
            app.TemperatureLabel = uilabel(app.GridLayout14);
            app.TemperatureLabel.Text = 'Temperature (K):';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.AtmosphericPressureSlider = uislider(app.GridLayout15);
            app.AtmosphericPressureSlider.Limits = [0.6 1];
            app.AtmosphericPressureSlider.MajorTicks = [0.608 0.6898 0.785 0.886 1];
            app.AtmosphericPressureSlider.MajorTickLabels = {'0.608 (4000 m)', '0.690 (3000 m)', '0.785 (2000 m)', '0.886 (1000 m)', '1.000 (Sea Level)'};
            app.AtmosphericPressureSlider.ValueChangedFcn = createCallbackFcn(app, @AtmosphericPressureSliderValueChanged, true);
            app.AtmosphericPressureSlider.MinorTicks = [];
            app.AtmosphericPressureSlider.Value = 1;
            app.AtmosphericPressureLabel = uilabel(app.GridLayout15);
            app.AtmosphericPressureLabel.Text = 'Atmospheric Pressure (atm)';
            app.VehicleParametersPanel = uipanel(app.GridLayout50);
            app.VehicleParametersPanel.Title = '   Vehicle Parameters';
            app.GridLayout23 = uigridlayout(app.VehicleParametersPanel);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.LoadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.LoadedWheelRadiusEditField.Limits = [0 Inf];
            app.LoadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @LoadedWheelRadiusEditFieldValueChanged, true);
            app.UnloadedWheelRadiusEditField = uieditfield(app.GridLayout24, 'numeric');
            app.UnloadedWheelRadiusEditField.Limits = [0 Inf];
            app.UnloadedWheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @UnloadedWheelRadiusEditFieldValueChanged, true);
            app.VehicleMassEditField = uieditfield(app.GridLayout24, 'numeric');
            app.VehicleMassEditField.Limits = [0 Inf];
            app.VehicleMassEditField.ValueChangedFcn = createCallbackFcn(app, @VehicleMassEditFieldValueChanged, true);
            app.LoadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.LoadedWheelRadiusLabel.Text = 'Loaded Wheel Radius (m):';
            app.UnloadedWheelRadiusLabel = uilabel(app.GridLayout24);
            app.UnloadedWheelRadiusLabel.Text = 'Unloaded Wheel Radius (m):';
            app.VehicleMassLabel = uilabel(app.GridLayout24);
            app.VehicleMassLabel.Text = 'Vehicle Mass (kg):';
            app.GridLayout39_2 = uigridlayout(app.GridLayout23);
            app.InitialBatterySOCSlider = uislider(app.GridLayout39_2);
            app.InitialBatterySOCSlider.ValueChangedFcn = createCallbackFcn(app, @InitialBatterySOCSliderValueChanged, true);
            app.InitialBatterySOCSlider.Value = 100;
            app.InitialBatterySOCLabel = uilabel(app.GridLayout39_2);
            app.InitialBatterySOCLabel.Text = 'Initial Battery SOC (%)';
            app.BatteryPanel = uipanel(app.GridLayout50);
            app.BatteryPanel.Title = '   Battery';
            app.GridLayout12 = uigridlayout(app.BatteryPanel);
            app.BatteryTypeLabel = uilabel(app.GridLayout12);
            app.BatteryTypeLabel.Text = '  Battery type: Lithium Ion';
            app.BatteryTable = uitable(app.GridLayout12);
            app.BatteryTable.ColumnName = {'Parameter'; 'Value'};
            app.BatteryTable.RowName = {};
            app.EnginePanel = uipanel(app.GridLayout50);
            app.EnginePanel.Title = '   Engine';
            app.GridLayout34 = uigridlayout(app.EnginePanel);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.SelectEngineTypeDropDownLabel = uilabel(app.GridLayout35);
            app.SelectEngineTypeDropDownLabel.Text = 'Select Engine Type:';
            app.SelectEngineTypeDropDown = uidropdown(app.GridLayout35);
            app.SelectEngineTypeDropDown.Items = {'SiMappedEngine', 'SiEngine'};
            app.SelectEngineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEngineTypeDropDownValueChanged, true);
            app.SelectEngineTypeDropDown.Tooltip = {'Select engine type'};
            app.SelectEngineTypeDropDown.Value = 'SiMappedEngine';
            app.GridLayout36 = uigridlayout(app.GridLayout34);
            app.ECMLabel = uilabel(app.GridLayout36);
            app.ECMLabel.Text = 'Engine Controller Module (ECM): SiEngineController';
            app.EngineTable = uitable(app.GridLayout36);
            app.EngineTable.ColumnName = {'Parameter'; 'Value'};
            app.EngineTable.RowName = {};
            app.SimulationandVisualizationTab = uitab(app.TabGroup);
            app.SimulationandVisualizationTab.Title = 'Simulation and Visualization';
            app.GridLayout51 = uigridlayout(app.SimulationandVisualizationTab);
            app.ControlPanel = uipanel(app.GridLayout51);
            app.ControlPanel.Title = '   Control';
            app.GridLayout17 = uigridlayout(app.ControlPanel);
            app.SimulationLamp = uilamp(app.GridLayout17);
            app.StatusLabel = uilabel(app.GridLayout17);
            app.StartSimulationButton = uibutton(app.GridLayout17, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Icon = 'greenarrowicon.gif';
            app.StartSimulationButton.Text = 'Start Simulation';
            app.GenerateReportButton = uibutton(app.GridLayout17, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Icon = 'pageicon.gif';
            app.GenerateReportButton.Enable = 'off';
            app.GenerateReportButton.Text = 'Generate Report';
            app.Switch = uiswitch(app.GridLayout17, 'slider');
            app.Switch.Items = {'Live', 'End of Simulation'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Live';
            app.ShowresultsLabel = uilabel(app.GridLayout17);
            app.ShowresultsLabel.Text = 'Show results:';
            app.GridLayout18 = uigridlayout(app.GridLayout51);
            app.OutputPlotsLabel = uilabel(app.GridLayout18);
            app.OutputPlotsLabel.Text = 'Output Plots:';
            app.Label = uilabel(app.GridLayout18);
            app.Label.Text = {'||'; '||'; '||'; '||'; '||'; '||'};
            app.EngineSpeedPanel = uipanel(app.GridLayout18);
            app.EngineSpeedPanel.Title = 'Engine / Speed';
            app.GridLayout50_2 = uigridlayout(app.EngineSpeedPanel);
            app.VehicleSpeedCheckBox = uicheckbox(app.GridLayout50_2);
            app.VehicleSpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @VehicleSpeedCheckBoxValueChanged, true);
            app.VehicleSpeedCheckBox.Text = 'Vehicle Speed';
            app.EngineMotorTorqueCheckBox = uicheckbox(app.GridLayout50_2);
            app.EngineMotorTorqueCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineMotorTorqueCheckBoxValueChanged, true);
            app.EngineMotorTorqueCheckBox.Text = 'Engine / Motor Torque (Nm)';
            app.EngineMotorSpeedCheckBoxnn = uicheckbox(app.GridLayout50_2);
            app.EngineMotorSpeedCheckBoxnn.ValueChangedFcn = createCallbackFcn(app, @EngineMotorSpeedCheckBoxnnValueChanged, true);
            app.EngineMotorSpeedCheckBoxnn.Text = 'Engine / Motor Speed (RPM)';
            app.BatteryPanel_2 = uipanel(app.GridLayout18);
            app.BatteryPanel_2.Title = 'Battery';
            app.GridLayout51_2 = uigridlayout(app.BatteryPanel_2);
            app.BatterySOCCheckBox = uicheckbox(app.GridLayout51_2);
            app.BatterySOCCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatterySOCCheckBoxValueChanged, true);
            app.BatterySOCCheckBox.Text = 'Battery SOC';
            app.BatteryCurrentCheckBox = uicheckbox(app.GridLayout51_2);
            app.BatteryCurrentCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatteryCurrentCheckBoxValueChanged, true);
            app.BatteryCurrentCheckBox.Text = 'Battery Current (A)';
            app.EmissionsPerformancePanel = uipanel(app.GridLayout18);
            app.EmissionsPerformancePanel.Title = 'Emissions / Performance';
            app.GridLayout52 = uigridlayout(app.EmissionsPerformancePanel);
            app.HCgmiCheckBox = uicheckbox(app.GridLayout52);
            app.HCgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @HCgmiCheckBoxValueChanged, true);
            app.HCgmiCheckBox.Text = 'HC (g/mi)';
            app.COgmiCheckBox = uicheckbox(app.GridLayout52);
            app.COgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @COgmiCheckBoxValueChanged, true);
            app.COgmiCheckBox.Text = 'CO (g/mi)';
            app.NOxgmiCheckBox = uicheckbox(app.GridLayout52);
            app.NOxgmiCheckBox.ValueChangedFcn = createCallbackFcn(app, @NOxgmiCheckBoxValueChanged, true);
            app.NOxgmiCheckBox.Text = 'NOx (g/mi)';
            app.CO2gkmCheckBox = uicheckbox(app.GridLayout52);
            app.CO2gkmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CO2gkmCheckBoxValueChanged, true);
            app.CO2gkmCheckBox.Text = 'CO2 (g/km)';
            app.USFuelEconomyCheckBox = uicheckbox(app.GridLayout52);
            app.USFuelEconomyCheckBox.ValueChangedFcn = createCallbackFcn(app, @USFuelEconomyCheckBoxValueChanged, true);
            app.USFuelEconomyCheckBox.Text = 'Fuel Economy (US MPGe)';
            app.FuelEconomyL100kmCheckBox = uicheckbox(app.GridLayout52);
            app.FuelEconomyL100kmCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuelEconomyL100kmCheckBoxValueChanged, true);
            app.FuelEconomyL100kmCheckBox.Text = 'Fuel Economy (L/100km)';
            app.Label_2 = uilabel(app.GridLayout18);
            app.Label_2.Text = {'||'; '||'; '||'; '||'; '||'; '||'};
            app.GridLayout53 = uigridlayout(app.GridLayout18);
            app.ClearAllButton = uibutton(app.GridLayout53, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = 'clear.png';
            app.ClearAllButton.Tooltip = {'Unselect all the output plots'};
            app.ClearAllButton.Text = 'Clear All';
            app.VisualizationPanel = uipanel(app.GridLayout51);
            app.VisualizationPanel.Title = '   Visualization';
            app.SessionLogTab = uitab(app.TabGroup);
            app.SessionLogTab.Title = 'Session Log';
            app.GridLayout54 = uigridlayout(app.SessionLogTab);
            app.SessiondetailsPanel = uipanel(app.GridLayout54);
            app.SessiondetailsPanel.Title = 'Session details';
            app.GridLayout43 = uigridlayout(app.SessiondetailsPanel);
            app.TimeLogLabel = uilabel(app.GridLayout43);
            app.TimeLogLabel.Text = 'Label3';
            app.SessionhistoryPanel = uipanel(app.GridLayout54);
            app.SessionhistoryPanel.Title = 'Session history';
            app.GridLayout42 = uigridlayout(app.SessionhistoryPanel);
            app.LogTextArea = uitextarea(app.GridLayout42);
            app.LogTextArea.Editable = 'off';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.GridLayout55 = uigridlayout(app.ReportTab);
            app.ReportAxes = uiaxes(app.GridLayout55);
            xlabel(app.ReportAxes, 'Time (s)')
            zlabel(app.ReportAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end